classdef AFM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        InspectFileSpinner            matlab.ui.control.Spinner
        InspectFileSpinnerLabel       matlab.ui.control.Label
        PlotselectedButton            matlab.ui.control.Button
        SelectForceCurveFolderButton  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectForceCurveFolderButton
        function SelectForceCurveFolderButtonPushed(app, event)
            script_dir = uigetdir();
            addpath(dir);
            addpath("./src/");
            path_incoming = fullfile(script_dir,'./incoming/');
            path_processed = fullfile(script_dir,'./processed/');
            path_raw = fullfile(script_dir,'./raw/');
          
            if ~exist(path_incoming,"dir")
                mkdir(path_incoming)
            end
            if ~exist(path_processed,"dir")
                mkdir(path_processed)
            end
            if ~exist(path_raw,"dir")
                mkdir(path_raw)
                force_dir = dir(fullfile(script_dir,'*.jpk-force'));
                for i = 1:length(force_dir)
                    base_str = force_dir(i).name;
                    src_file = fullfile(dir, base_str);
                    dest_file = fullfile(path_raw, base_str);
                    
                    try
                        copyfile(src_file, dest_file);
                        if exist(dest_file, 'file') == 2
                            delete(src_file);
                        else
                            error('File copy failed: %s', base_str);
                        end
                    catch ME
                        fprintf('Error occurred: %s. File was not deleted: %s\n', ME.message, base_str);
                    end
                end
            end

            force_dir = dir(fullfile(path_raw,'*.jpk-force'));
            for i = 1:length(force_dir)
                base_str =force_dir(i).name;
                extract(path_raw,path_incoming,base_str,2);
            end
        end

        % Button pushed function: PlotselectedButton
        function PlotselectedButtonPushed(app, event)
            curve_id = app.InspectFileSpinner.value;
            base_str = force_dir(curve_id).name;
            load(fullfile(path_incoming,[base_str,'_approach.mat']))
            plot(app.UIAxes, approach_height,approach_deflection)
            xlabel('Approach Height [a.U.]');
            ylabel('Deflection [a.U.]');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Approach Height [a.U.]')
            ylabel(app.UIAxes, 'Deflection [a.U.]')
            zlabel(app.UIAxes, 'Z')
            app.SelectForceCurveFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectForceCurveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectForceCurveFolderButtonPushed, true);
            app.SelectForceCurveFolderButton.Text = 'Select Force Curve Folder';
            app.PlotselectedButton = uibutton(app.UIFigure, 'push');
            app.PlotselectedButton.ButtonPushedFcn = createCallbackFcn(app, @PlotselectedButtonPushed, true);
            app.PlotselectedButton.Text = 'Plot selected';
            app.InspectFileSpinnerLabel = uilabel(app.UIFigure);
            app.InspectFileSpinnerLabel.Text = 'Inspect File';
            app.InspectFileSpinner = uispinner(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AFM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end